classdef balanceInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GraphiqueTab                   matlab.ui.container.Tab
        SimulerButton                  matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        RinitialiserButton             matlab.ui.control.Button
        SimulationdelabalanceLabel     matlab.ui.control.Label
        MasseSlider                    matlab.ui.control.Slider
        MassechoisieEditFieldLabel     matlab.ui.control.Label
        MassechoisieEditField          matlab.ui.control.NumericEditField
        MasseafficheEditFieldLabel     matlab.ui.control.Label
        MasseafficheEditField          matlab.ui.control.NumericEditField
        SauvegarderEditField           matlab.ui.control.EditField
        SauvegarderButton              matlab.ui.control.Button
        TarerButton                    matlab.ui.control.Button
        ValeurtareEditFieldLabel       matlab.ui.control.Label
        ValeurtareEditField            matlab.ui.control.NumericEditField
        AnnulerButton                  matlab.ui.control.Button
        TempsdestabilisationEditFieldLabel  matlab.ui.control.Label
        TempsdestabilisationEditField  matlab.ui.control.NumericEditField
        GraphiqueButtonGroup           matlab.ui.container.ButtonGroup
        LameButton                     matlab.ui.control.RadioButton
        CapteurdepositionButton        matlab.ui.control.RadioButton
        ActionneurButton               matlab.ui.control.RadioButton
        PIDButton_2                    matlab.ui.control.RadioButton
        AmplificateurButton            matlab.ui.control.RadioButton
        AnimationButton                matlab.ui.control.RadioButton
        CompterButton                  matlab.ui.control.Button
        CompterEditField               matlab.ui.control.NumericEditField
        NombredepicesLabel             matlab.ui.control.Label
        ChargerEditField               matlab.ui.control.EditField
        ChargerButton                  matlab.ui.control.Button
        ParamtrisationTab              matlab.ui.container.Tab
        ParamtresLabel                 matlab.ui.control.Label
        ActionneurLabel                matlab.ui.control.Label
        ChampmagntiqueEditFieldLabel   matlab.ui.control.Label
        ChampmagntiqueEditField        matlab.ui.control.NumericEditField
        DiamtreEditFieldLabel          matlab.ui.control.Label
        DiamtreEditField               matlab.ui.control.NumericEditField
        NombredetoursEditFieldLabel    matlab.ui.control.Label
        NombredetoursEditField         matlab.ui.control.NumericEditField
        CoefficientdefrottementEditFieldLabel  matlab.ui.control.Label
        CoefficientdefrottementEditField  matlab.ui.control.NumericEditField
        InertieEditFieldLabel          matlab.ui.control.Label
        InertieEditField               matlab.ui.control.NumericEditField
        AmplificateurLabel             matlab.ui.control.Label
        TensiondeoffsetEditFieldLabel  matlab.ui.control.Label
        TensiondeoffsetEditField       matlab.ui.control.NumericEditField
        GaindelampliaudioEditFieldLabel  matlab.ui.control.Label
        GaindelampliaudioEditField     matlab.ui.control.NumericEditField
        ConstantedetempsdelampliaudioEditFieldLabel  matlab.ui.control.Label
        ConstantedetempsdelampliaudioEditField  matlab.ui.control.NumericEditField
        InductancedelabobineEditFieldLabel  matlab.ui.control.Label
        InductancedelabobineEditField  matlab.ui.control.NumericEditField
        RsistancequivalentedepuissanceEditFieldLabel  matlab.ui.control.Label
        RsistancequivalentedepuissanceEditField  matlab.ui.control.NumericEditField
        RsistancedelabobineEditFieldLabel  matlab.ui.control.Label
        RsistancedelabobineEditField   matlab.ui.control.NumericEditField
        ConstantecontrelectromotriceEditFieldLabel  matlab.ui.control.Label
        ConstantecontrelectromotriceEditField  matlab.ui.control.NumericEditField
        GaincouranttensionEditFieldLabel  matlab.ui.control.Label
        GaincouranttensionEditField    matlab.ui.control.NumericEditField
        CapteurdepositionLabel         matlab.ui.control.Label
        AmplitudedeloscillateurEditFieldLabel  matlab.ui.control.Label
        AmplitudedeloscillateurEditField  matlab.ui.control.NumericEditField
        DistanceentreleslectrodesEditFieldLabel  matlab.ui.control.Label
        DistanceentreleslectrodesEditField  matlab.ui.control.NumericEditField
        Tensiondcalage1erampliEditFieldLabel  matlab.ui.control.Label
        Tensiondcalage1erampliEditField  matlab.ui.control.NumericEditField
        LameLabel                      matlab.ui.control.Label
        LargeurEditFieldLabel          matlab.ui.control.Label
        LargeurEditField               matlab.ui.control.NumericEditField
        paisseurEditFieldLabel         matlab.ui.control.Label
        paisseurEditField              matlab.ui.control.NumericEditField
        LongueuractiveEditFieldLabel   matlab.ui.control.Label
        LongueuractiveEditField        matlab.ui.control.NumericEditField
        MassechoisiegEditFieldLabel    matlab.ui.control.Label
        MassechoisiegEditField         matlab.ui.control.NumericEditField
        SimulerButton_2                matlab.ui.control.Button
        ValeurspardfautButton          matlab.ui.control.Button
        MasseaffichegEditFieldLabel    matlab.ui.control.Label
        MasseaffichegEditField         matlab.ui.control.NumericEditField
        Tensiondedcalagedu2eampliEditFieldLabel  matlab.ui.control.Label
        Tensiondedcalagedu2eampliEditField  matlab.ui.control.NumericEditField
        Rsistance1EditFieldLabel       matlab.ui.control.Label
        Rsistance1EditField            matlab.ui.control.NumericEditField
        Rsistance2EditFieldLabel       matlab.ui.control.Label
        Rsistance2EditField            matlab.ui.control.NumericEditField
        Rsistance4EditFieldLabel       matlab.ui.control.Label
        Rsistance4EditField            matlab.ui.control.NumericEditField
        Rsistance3EditFieldLabel       matlab.ui.control.Label
        Rsistance3EditField            matlab.ui.control.NumericEditField
        ConstantedrivateurEditFieldLabel  matlab.ui.control.Label
        ConstantedrivateurEditField    matlab.ui.control.NumericEditField
        ConstanteintgrateurEditFieldLabel  matlab.ui.control.Label
        ConstanteintgrateurEditField   matlab.ui.control.NumericEditField
        ConstanteproportionelleEditFieldLabel  matlab.ui.control.Label
        ConstanteproportionelleEditField  matlab.ui.control.NumericEditField
        PIDLabel                       matlab.ui.control.Label
        DensitEditFieldLabel           matlab.ui.control.Label
        DensitEditField                matlab.ui.control.NumericEditField
        FrquencenaturelleEditFieldLabel  matlab.ui.control.Label
        FrquencenaturelleEditField     matlab.ui.control.NumericEditField
        SauvegarderParamEditField      matlab.ui.control.EditField
        SauvegarderParamButton         matlab.ui.control.Button
        ChargerParamEditField          matlab.ui.control.EditField
        ChargerParamButton             matlab.ui.control.Button
        ModuledeYoungEditFieldLabel    matlab.ui.control.Label
        ModuledeYoungEditField         matlab.ui.control.NumericEditField
        AmortissementEditFieldLabel    matlab.ui.control.Label
        AmortissementEditField         matlab.ui.control.NumericEditField
        NombredepointsEditFieldLabel   matlab.ui.control.Label
        NombredepointsEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulerButton, SimulerButton_2
        function SimulerButtonPushed(app, event)
            global Out dx L
          
            %ParamÃ¨tres.e la poutre
            b = app.LargeurEditField.Value
            h = app.paisseurEditField.Value
            L = app.LongueuractiveEditField.Value;
            densite = app.DensitEditField.Value;
            f = app.FrquencenaturelleEditField.Value;
            %DensitÃ© linÃ©ique
            mu = densite*b*h;
            %Moment quadratique
            J = b*h^3/12;
            %Module de Young
            %E = (mu*(2*pi*f)^2/J)*(L/1.875)^4;
            %E = 1.4432*10^10;
            E=app.ModuledeYoungEditField.Value
            %Amortissement
            gamma = 1.5*5.91;
            %B = gamma*(mu*L + app.MassechoisieEditField.Value);
            B=app.AmortissementEditField.Value
            %Nombre d'incrÃ©ments Ã  calculer (par exemple, 0 Ã  11000 bonds de dt dans la
            %simulation)
            nx=app.NombredepointsEditField.Value
            %IncrÃ©ments
            dt = 6.05e-5;
            dx = L/(nx-1);
            %Liste d'Ã©lÃ©ments spaciaux
            nx = nx + 2;
            %Array de force appliquÃ©e
            F = zeros(1, nx);
            
            % w = 1 * fliplr(0.001*sin(pi*x/(2*L)));
            %Initialisation des arrays d'Ã©tat passÃ©, prÃ©sent et futur
            w_old = zeros(1, nx);
            w = zeros(1, nx);
            w_new = zeros(1, nx);
          
            
            %Position du bout de la lame en fonction du temps
            %position_bout = zeros(1, nt+1); 
            
            %Pour l'EDO
            coeff1 = (E * J)/(dx^4);
            coeff2 = mu/(dt^2);
            coeff3 = B/dt;
            coeff4 = ((mu/dt^2)+(B/dt))^(-1);
            coeffs = [coeff1, coeff2, coeff3, coeff4];
           

            assignin("base",'b',b);
            assignin("base",'longueur',L);
            assignin("base",'densite',densite);
            assignin("base",'frequence',f);

            assignin("base",'mLame',mu*L);
            assignin("base",'coeffs',coeffs);
            assignin("base",'nx',nx);
            assignin("base",'dx',dx);
            assignin("base",'w_old',w_old);
            assignin("base",'w',w);
            assignin("base",'w_new',w_new);
            assignin("base",'F',F);
            assignin("base",'dt',dt);

            assignin("base", "masse", app.MasseSlider.Value/1000)
           
            assignin("base","B",app.ChampmagntiqueEditField.Value)
            assignin("base","D",app.DiamtreEditField.Value)
            assignin("base","N",app.NombredetoursEditField.Value)
            assignin("base","f",app.CoefficientdefrottementEditField.Value)
            assignin("base","J",app.InertieEditField.Value)
            
            assignin("base","Voffset", app.TensiondeoffsetEditField.Value)
            assignin("base", "Kamp", app.GaindelampliaudioEditField.Value)
            assignin("base","to", app.ConstantedetempsdelampliaudioEditField.Value)
            assignin("base","L", app.InductancedelabobineEditField.Value)
            assignin("base","Req", app.RsistancequivalentedepuissanceEditField.Value)
            assignin("base", "R", app.RsistancedelabobineEditField.Value)
            assignin("base","Ke", app.ConstantecontrelectromotriceEditField.Value)
            assignin("base","Kc", app.GaincouranttensionEditField.Value)
            
            assignin("base", "aO", app.AmplitudedeloscillateurEditField.Value)
            assignin("base", "dE", app.DistanceentreleslectrodesEditField.Value)
            assignin("base", "eL", app.paisseurEditField.Value)
            assignin("base", "Vd1", app.Tensiondcalage1erampliEditField.Value)
            assignin("base", "R1", app.Rsistance1EditField.Value)
            assignin("base", "R2", app.Rsistance2EditField.Value)
            assignin("base", "Vd2", app.Tensiondedcalagedu2eampliEditField.Value)
            assignin("base", "R3", app.Rsistance3EditField.Value)
            assignin("base", "R4", app.Rsistance4EditField.Value)
            
            assignin("base", "kp", app.ConstanteproportionelleEditField.Value)
            assignin("base", "ki", app.ConstanteintgrateurEditField.Value)
            assignin("base", "kd", app.ConstantedrivateurEditField.Value)
            
            Out = sim("balanceCAOv1.slx")
            
            selectedButton = app.GraphiqueButtonGroup.SelectedObject;
            
                if (selectedButton == app.LameButton)
                    plot(app.UIAxes, Out.lame.Time, Out.lame.Data)
                    title(app.UIAxes,"Le déplacement du bout de la lame en fonction du temps")
                    ylabel(app.UIAxes,"x (m)")
                    ylim(app.UIAxes,"auto")
                    xlim(app.UIAxes,"auto")
                    
                elseif (selectedButton == app.CapteurdepositionButton)
                    plot(app.UIAxes, Out.capteur.Time, Out.capteur.Data)
                    title(app.UIAxes,"La tension à la sortie du capteur de position en fonction du temps")
                    ylabel(app.UIAxes,"tension (V)")
                    ylim(app.UIAxes,"auto")
                    xlim(app.UIAxes,"auto")
                    
                elseif (selectedButton == app.PIDButton_2)
                    plot(app.UIAxes, Out.pid.Time, Out.pid.Data)
                    title(app.UIAxes,"La tension à la sortie du PID en fonction du temps")
                    ylabel(app.UIAxes,"tension (V)")
                    ylim(app.UIAxes,"auto")
                    xlim(app.UIAxes,"auto")
                    
                elseif (selectedButton == app.AmplificateurButton)
                    plot(app.UIAxes, Out.amplificateur.Time, Out.amplificateur.Data)
                    title(app.UIAxes,"Le courant dans la bobine en fonction du temps")
                    ylabel(app.UIAxes,"I (A)")
                    ylim(app.UIAxes,"auto")
                    xlim(app.UIAxes,"auto")
                    
                elseif (selectedButton == app.ActionneurButton)
                    plot(app.UIAxes, Out.actionneur.Time, Out.actionneur.Data)  
                    title(app.UIAxes,"La force de l'actionneur en fonction du temps")
                    ylabel(app.UIAxes,"F (N)")
                    ylim(app.UIAxes,"auto")
                    xlim(app.UIAxes,"auto")
                    
                elseif (selectedButton == app.AnimationButton)
                    x  = -dx:dx:L+dx;
                    h = plot(app.UIAxes,x,Out.animation.Data(1,:))
                    ylim(app.UIAxes,[-0.0017,0.0017])
                    xlim(app.UIAxes,[-0.05,0.3])
                    title(app.UIAxes,"Allure de la lame pendant la stabilisation")
                    ylabel(app.UIAxes,"Déflexion [m]")
                        for i=2:size(Out.animation.Data,1)
                            if isvalid(h)
                            set(h(1),"Ydata",Out.animation.Data(i,:))
                            drawnow
                            end
                        end
                end
                
                x = find(Out.tempsSim.Data(9600:end) <= 5/1023, 1)
                
                app.TempsdestabilisationEditField.Value = Out.tempsSim.Time(x+9600)
                app.MasseafficheEditField.Value = Out.balance.Data(end) - app.ValeurtareEditField.Value
                app.MasseaffichegEditField.Value = Out.balance.Data(end) - app.ValeurtareEditField.Value
                
                if(app.MasseafficheEditField.Value==0)
                    app.TempsdestabilisationEditField.Value=0
                end
        end

        % Value changing function: MasseSlider
        function MasseSliderValueChanging(app, event)
            changingValue = event.Value;
            app.MassechoisieEditField.Value = changingValue
            app.MassechoisiegEditField.Value = changingValue
            
            densite = app.DensitEditField.Value;
            L = app.LongueuractiveEditField.Value
            h = app.paisseurEditField.Value
            b = app.LargeurEditField.Value
            
            mu = densite*b*h;
            
            gamma = 2.5*5.91;
            app.AmortissementEditField.Value = gamma*(mu*L + app.MassechoisieEditField.Value);
        end

        % Value changed function: MassechoisieEditField
        function MassechoisieEditFieldValueChanged(app, event)
            value = app.MassechoisieEditField.Value;
            app.MassechoisiegEditField.Value = value
            app.MasseSlider.Value = value
            
            
            densite = app.DensitEditField.Value;
            L = app.LongueuractiveEditField.Value
            h = app.paisseurEditField.Value
            b = app.LargeurEditField.Value
            
            mu = densite*b*h;
            
            gamma = 2.5*5.91;
            app.AmortissementEditField.Value = gamma*(mu*L + app.MassechoisieEditField.Value);
        end

        % Button pushed function: RinitialiserButton
        function RinitialiserButtonPushed(app, event)
            app.MasseafficheEditField.Value = 0
            app.TempsdestabilisationEditField.Value = 0
            app.ValeurtareEditField.Value = 0
            app.NombredepointsEditField.Value = 0
            cla(app.UIAxes)
            app.SauvegarderEditField.Value = ""
        end

        % Button pushed function: SauvegarderButton
        function SauvegarderButtonPushed(app, event)
            global Out
            save("Saves\" + app.SauvegarderEditField.Value, "Out")
        end

        % Button pushed function: ValeurspardfautButton
        function ValeurspardfautButtonPushed(app, event)
            app.MassechoisieEditField.Value = 0
            app.MassechoisiegEditField.Value=0
            app.MasseSlider.Value=0
            
            app.LargeurEditField.Value = 0.074
            app.paisseurEditField.Value = 0.00159
            app.LongueuractiveEditField.Value = 0.24
            app.DensitEditField.Value = 1937
            app.FrquencenaturelleEditField.Value = 12.17
            app.NombredepointsEditField.Value = 20
            app.ModuledeYoungEditField.Value = 20060998527
            app.AmortissementEditField.Value = 0.8082
            
            app.ChampmagntiqueEditField.Value = 0.1128
            app.DiamtreEditField.Value = 0.0406
            app.NombredetoursEditField.Value = 66
            app.CoefficientdefrottementEditField.Value = 0
            app.InertieEditField.Value = 0
            
            app.TensiondeoffsetEditField.Value = 2.55
            app.GaindelampliaudioEditField.Value = 4.02
            app.ConstantedetempsdelampliaudioEditField.Value = 0.00000462
            app.InductancedelabobineEditField.Value = 0.00095
            app.RsistancequivalentedepuissanceEditField.Value = 1.01
            app.RsistancedelabobineEditField.Value = 1.43
            app.ConstantecontrelectromotriceEditField.Value = 0
            app.GaincouranttensionEditField.Value = 3.04
            
            app.AmplitudedeloscillateurEditField.Value = 1.2
            app.DistanceentreleslectrodesEditField.Value = 0.005
            app.Tensiondcalage1erampliEditField.Value = -0.5
            app.Rsistance1EditField.Value = 1000
            app.Rsistance2EditField.Value = 1000
            app.Tensiondedcalagedu2eampliEditField.Value = 0.51
            app.Rsistance3EditField.Value = 1000
            app.Rsistance4EditField.Value = 4700
            
            app.ConstanteproportionelleEditField.Value = 0.0067
            app.ConstanteintgrateurEditField.Value = 0.132
            app.ConstantedrivateurEditField.Value = 0
        end

        % Value changed function: MassechoisiegEditField
        function MassechoisiegEditFieldValueChanged(app, event)
            value = app.MassechoisiegEditField.Value;
            app.MassechoisieEditField.Value = value
            app.MasseSlider.Value = value
            
            densite = app.DensitEditField.Value;
            L = app.LongueuractiveEditField.Value
            h = app.paisseurEditField.Value
            b = app.LargeurEditField.Value
            
            mu = densite*b*h;
            
            gamma = 2.5*5.91;
            app.AmortissementEditField.Value = gamma*(mu*L + app.MassechoisieEditField.Value);
        end

        % Button pushed function: TarerButton
        function TarerButtonPushed(app, event)
            app.ValeurtareEditField.Value = app.MasseafficheEditField.Value
        end

        % Button pushed function: AnnulerButton
        function AnnulerButtonPushed(app, event)
            app.ValeurtareEditField.Value = 0
        end

        % Selection changed function: GraphiqueButtonGroup
        function GraphiqueButtonGroupSelectionChanged(app, event)
            global Out dx L
            selectedButton = app.GraphiqueButtonGroup.SelectedObject;
                if (selectedButton == app.LameButton)
                    plot(app.UIAxes, Out.lame.Time, Out.lame.Data)
                    title(app.UIAxes,"Le déplacement du bout de la lame en fonction du temps")
                    ylabel(app.UIAxes,"x (m)")
                    ylim(app.UIAxes,"auto")
                    xlim(app.UIAxes,"auto")
                    
                elseif (selectedButton == app.CapteurdepositionButton)
                    plot(app.UIAxes, Out.capteur.Time, Out.capteur.Data)
                    title(app.UIAxes,"La tension à la sortie du capteur de position en fonction du temps")
                    ylabel(app.UIAxes,"tension (V)")
                    ylim(app.UIAxes,"auto")
                    xlim(app.UIAxes,"auto")
                    
                elseif (selectedButton == app.PIDButton_2)
                    plot(app.UIAxes, Out.pid.Time, Out.pid.Data)
                    title(app.UIAxes,"La tension à la sortie du PID en fonction du temps")
                    ylabel(app.UIAxes,"tension (V)")
                    ylim(app.UIAxes,"auto")
                    xlim(app.UIAxes,"auto")
                    
                elseif (selectedButton == app.AmplificateurButton)
                    plot(app.UIAxes, Out.amplificateur.Time, Out.amplificateur.Data)
                    title(app.UIAxes,"Le courant dans la bobine en fonction du temps")
                    ylabel(app.UIAxes,"I (A)")
                    ylim(app.UIAxes,"auto")
                    xlim(app.UIAxes,"auto")
                    
                elseif (selectedButton == app.ActionneurButton)
                    plot(app.UIAxes, Out.actionneur.Time, Out.actionneur.Data)
                    title(app.UIAxes,"La force de l'actionneur en fonction du temps")
                    ylabel(app.UIAxes,"F (N)")
                    ylim(app.UIAxes,"auto")
                    xlim(app.UIAxes,"auto")
                    
                elseif (selectedButton == app.AnimationButton)
                    x  = -dx:dx:L+dx
                    h = plot(app.UIAxes,x,Out.animation.Data(1,:))
                    ylim(app.UIAxes,[-0.0017,0.0017])
                    xlim(app.UIAxes,[-0.05,0.3])
                    title(app.UIAxes,"La force de l'actionneur en fonction du temps")
                    ylabel(app.UIAxes,"Déflexion [mm]")
                   
                    for i=2:size(Out.animation.Data,1)
                        if isvalid(h)
                        set(h(1),"Ydata",Out.animation.Data(i,:))
                        drawnow
                        end
                    end
                  
                end
        end

        % Button pushed function: CompterButton
        function CompterButtonPushed(app, event)
            global Out
            app.CompterEditField.Value = round((Out.balance.Data(end))/app.ValeurtareEditField.Value)
        end

        % Button pushed function: ChargerButton
        function ChargerButtonPushed(app, event)
                load("Saves\"+app.ChargerEditField.Value,"Out")
            
                selectedButton = app.GraphiqueButtonGroup.SelectedObject;
            
                if (selectedButton == app.LameButton)
                    plot(app.UIAxes, Out.lame.Time, Out.lame.Data)
                    title(app.UIAxes,"Le déplacement du bout de la lame en fonction du temps")
                    ylabel(app.UIAxes,"x (m)")
                    ylim(app.UIAxes,"auto")
                    xlim(app.UIAxes,"auto")
                    
                elseif (selectedButton == app.CapteurdepositionButton)
                    plot(app.UIAxes, Out.capteur.Time, Out.capteur.Data)
                    title(app.UIAxes,"La tension à la sortie du capteur de position en fonction du temps")
                    ylabel(app.UIAxes,"tension (V)")
                    ylim(app.UIAxes,"auto")
                    xlim(app.UIAxes,"auto")
                    
                elseif (selectedButton == app.PIDButton_2)
                    plot(app.UIAxes, Out.pid.Time, Out.pid.Data)
                    title(app.UIAxes,"La tension à la sortie du PID en fonction du temps")
                    ylabel(app.UIAxes,"tension (V)")
                    ylim(app.UIAxes,"auto")
                    xlim(app.UIAxes,"auto")
                    
                elseif (selectedButton == app.AmplificateurButton)
                    plot(app.UIAxes, Out.amplificateur.Time, Out.amplificateur.Data)
                    title(app.UIAxes,"Le courant dans la bobine en fonction du temps")
                    ylabel(app.UIAxes,"I (A)")
                    ylim(app.UIAxes,"auto")
                    xlim(app.UIAxes,"auto")
                    
                elseif (selectedButton == app.ActionneurButton)
                    plot(app.UIAxes, Out.actionneur.Time, Out.actionneur.Data)  
                    title(app.UIAxes,"La force de l'actionneur en fonction du temps")
                    ylabel(app.UIAxes,"F (N)")
                    ylim(app.UIAxes,"auto")
                    xlim(app.UIAxes,"auto")
                    
                elseif (selectedButton == app.AnimationButton)
                    x  = -dx:dx:L+dx;
                    h = plot(app.UIAxes,x,Out.animation.Data(1,:))
                    ylim(app.UIAxes,[-0.0017,0.0017])
                    xlim(app.UIAxes,[-0.05,0.3])
                    title(app.UIAxes,"Allure de la lame pendant la stabilisation")
                    ylabel(app.UIAxes,"Déflexion [m]")
                        for i=2:size(Out.animation.Data,1)
                        set(h(1),"Ydata",Out.animation.Data(i,:))
                        drawnow
                        end
                end
                
                x = find(Out.tempsSim.Data(9600:end) <= 5/1023, 1)
                app.TempsdestabilisationEditField.Value = Out.tempsSim.Time(x+9600)
                app.MasseafficheEditField.Value = Out.balance.Data(end) - app.ValeurtareEditField.Value
                app.MasseaffichegEditField.Value = Out.balance.Data(end) - app.ValeurtareEditField.Value
                
                if(app.MasseafficheEditField.Value==0)
                    app.TempsdestabilisationEditField.Value=0
                end
        end

        % Button pushed function: SauvegarderParamButton
        function SauvegarderParamButtonPushed(app, event)
            b=app.LargeurEditField.Value
            longueur=app.LongueuractiveEditField.Value
            densite= app.DensitEditField.Value
            frequence=app.FrquencenaturelleEditField.Value
            nx=app.NombredepointsEditField.Value
           
            B=app.ChampmagntiqueEditField.Value
            D=app.DiamtreEditField.Value
            N=app.NombredetoursEditField.Value
            f=app.CoefficientdefrottementEditField.Value
            J=app.InertieEditField.Value
            
            Voffset=app.TensiondeoffsetEditField.Value
            Kamp=app.GaindelampliaudioEditField.Value
            to=app.ConstantedetempsdelampliaudioEditField.Value
            L=app.InductancedelabobineEditField.Value
            Req=app.RsistancequivalentedepuissanceEditField.Value
            R=app.RsistancedelabobineEditField.Value
            Ke=app.ConstantecontrelectromotriceEditField.Value
            Kc=app.GaincouranttensionEditField.Value
            
            aO=app.AmplitudedeloscillateurEditField.Value
            dE=app.DistanceentreleslectrodesEditField.Value
            eL=app.paisseurEditField.Value
            Vd1=app.Tensiondcalage1erampliEditField.Value
            R1=app.Rsistance1EditField.Value
            R2=app.Rsistance2EditField.Value
            Vd2=app.Tensiondedcalagedu2eampliEditField.Value
            R3=app.Rsistance3EditField.Value
            R4=app.Rsistance4EditField.Value
            
            kp=app.ConstanteproportionelleEditField.Value
            ki=app.ConstanteintgrateurEditField.Value
            kd=app.ConstantedrivateurEditField.Value
            save("Saves\"+app.SauvegarderParamEditField.Value,"b","longueur","densite","frequence","nx","B","D","N","f","J","Voffset","Kamp","to","L","Req","R","Ke","Kc","aO","dE","eL","Vd1","R1","R2","Vd2","R3","R4","kp","ki","kd")
        end

        % Button pushed function: ChargerParamButton
        function ChargerParamButtonPushed(app, event)
            load("Saves\"+app.ChargerParamEditField.Value,"b","longueur","densite","frequence","nx","B","D","N","f","J","Voffset","Kamp","to","L","Req","R","Ke","Kc","aO","dE","eL","Vd1","R1","R2","Vd2","R3","R4","kp","ki","kd")
            
            
            app.LargeurEditField.Value = b
            app.paisseurEditField.Value = eL
            app.LongueuractiveEditField.Value = longueur
            app.DensitEditField.Value = densite
            app.FrquencenaturelleEditField.Value = frequence
            app.NombredepointsEditField.Value = nx
            
            mu = densite*b*eL;
            %Moment quadratique
            t = b*eL^3/12;
            %Module de Young
            app.ModuledeYoungEditField.Value = 1.39*(mu*(2*pi*frequence)^2/t)*(longueur/1.875)^4;
            
            gamma = 2.5*5.91;
            app.AmortissementEditField.Value = gamma*(mu*longueur + app.MassechoisieEditField.Value);
            
            
            app.ChampmagntiqueEditField.Value = B
            app.DiamtreEditField.Value = D
            app.NombredetoursEditField.Value = N
            app.CoefficientdefrottementEditField.Value = f
            app.InertieEditField.Value = J
            
            app.TensiondeoffsetEditField.Value = Voffset
            app.GaindelampliaudioEditField.Value = Kamp
            app.ConstantedetempsdelampliaudioEditField.Value = to
            app.InductancedelabobineEditField.Value = L
            app.RsistancequivalentedepuissanceEditField.Value = Req
            app.RsistancedelabobineEditField.Value = R
            app.ConstantecontrelectromotriceEditField.Value = Ke
            app.GaincouranttensionEditField.Value = Kc
            
            app.AmplitudedeloscillateurEditField.Value = aO
            app.DistanceentreleslectrodesEditField.Value = dE
            app.Tensiondcalage1erampliEditField.Value = Vd1
            app.Rsistance1EditField.Value = R1
            app.Rsistance2EditField.Value = R2
            app.Tensiondedcalagedu2eampliEditField.Value = Vd2
            app.Rsistance3EditField.Value = R3
            app.Rsistance4EditField.Value = R4
            
            app.ConstanteproportionelleEditField.Value = kp
            app.ConstanteintgrateurEditField.Value = ki
            app.ConstantedrivateurEditField.Value = kd
        end

        % Value changed function: DensitEditField, 
        % FrquencenaturelleEditField, LargeurEditField, 
        % LongueuractiveEditField, paisseurEditField
        function ModuleDeYoungChanged(app, event)
            densite = app.DensitEditField.Value;
            L = app.LongueuractiveEditField.Value
            h = app.paisseurEditField.Value
            b = app.LargeurEditField.Value
            f= app.FrquencenaturelleEditField.Value
            
            %DensitÃ© linÃ©ique
            mu = densite*b*h;
            %Moment quadratique
            J = b*h^3/12;
            %Module de Young
            app.ModuledeYoungEditField.Value = 1.39*(mu*(2*pi*f)^2/J)*(L/1.875)^4;
            
            gamma = 2.5*5.91;
            app.AmortissementEditField.Value = gamma*(mu*L + app.MassechoisieEditField.Value);
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GraphiqueTab = uitab(app.TabGroup);
            app.GraphiqueTab.Title = 'Graphique';
            app.SimulerButton = uibutton(app.GraphiqueTab, 'push');
            app.SimulerButton.ButtonPushedFcn = createCallbackFcn(app, @SimulerButtonPushed, true);
            app.SimulerButton.Text = 'Simuler';
            app.UIAxes = uiaxes(app.GraphiqueTab);
            title(app.UIAxes, 'Graphique')
            xlabel(app.UIAxes, 'temps (s)')
            ylabel(app.UIAxes, 'y')
            app.RinitialiserButton = uibutton(app.GraphiqueTab, 'push');
            app.RinitialiserButton.ButtonPushedFcn = createCallbackFcn(app, @RinitialiserButtonPushed, true);
            app.RinitialiserButton.Text = 'Réinitialiser';
            app.SimulationdelabalanceLabel = uilabel(app.GraphiqueTab);
            app.SimulationdelabalanceLabel.Text = {'Simulation'; 'de la balance'};
            app.MasseSlider = uislider(app.GraphiqueTab);
            app.MasseSlider.ValueChangingFcn = createCallbackFcn(app, @MasseSliderValueChanging, true);
            app.MassechoisieEditFieldLabel = uilabel(app.GraphiqueTab);
            app.MassechoisieEditFieldLabel.Text = 'Masse choisie';
            app.MassechoisieEditField = uieditfield(app.GraphiqueTab, 'numeric');
            app.MassechoisieEditField.ValueChangedFcn = createCallbackFcn(app, @MassechoisieEditFieldValueChanged, true);
            app.MasseafficheEditFieldLabel = uilabel(app.GraphiqueTab);
            app.MasseafficheEditFieldLabel.Text = 'Masse affichée';
            app.MasseafficheEditField = uieditfield(app.GraphiqueTab, 'numeric');
            app.MasseafficheEditField.Editable = 'off';
            app.SauvegarderEditField = uieditfield(app.GraphiqueTab, 'text');
            app.SauvegarderButton = uibutton(app.GraphiqueTab, 'push');
            app.SauvegarderButton.ButtonPushedFcn = createCallbackFcn(app, @SauvegarderButtonPushed, true);
            app.SauvegarderButton.Text = 'Sauvegarder';
            app.TarerButton = uibutton(app.GraphiqueTab, 'push');
            app.TarerButton.ButtonPushedFcn = createCallbackFcn(app, @TarerButtonPushed, true);
            app.TarerButton.Text = 'Tarer';
            app.ValeurtareEditFieldLabel = uilabel(app.GraphiqueTab);
            app.ValeurtareEditFieldLabel.Text = 'Valeur tarée';
            app.ValeurtareEditField = uieditfield(app.GraphiqueTab, 'numeric');
            app.AnnulerButton = uibutton(app.GraphiqueTab, 'push');
            app.AnnulerButton.ButtonPushedFcn = createCallbackFcn(app, @AnnulerButtonPushed, true);
            app.AnnulerButton.Text = 'Annuler';
            app.TempsdestabilisationEditFieldLabel = uilabel(app.GraphiqueTab);
            app.TempsdestabilisationEditFieldLabel.Text = 'Temps de stabilisation';
            app.TempsdestabilisationEditField = uieditfield(app.GraphiqueTab, 'numeric');
            app.TempsdestabilisationEditField.Editable = 'off';
            app.GraphiqueButtonGroup = uibuttongroup(app.GraphiqueTab);
            app.GraphiqueButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GraphiqueButtonGroupSelectionChanged, true);
            app.GraphiqueButtonGroup.Title = 'Graphique';
            app.LameButton = uiradiobutton(app.GraphiqueButtonGroup);
            app.LameButton.Text = 'Lame';
            app.LameButton.Value = true;
            app.CapteurdepositionButton = uiradiobutton(app.GraphiqueButtonGroup);
            app.CapteurdepositionButton.Text = 'Capteur de position';
            app.ActionneurButton = uiradiobutton(app.GraphiqueButtonGroup);
            app.ActionneurButton.Text = 'Actionneur';
            app.PIDButton_2 = uiradiobutton(app.GraphiqueButtonGroup);
            app.PIDButton_2.Text = 'PID';
            app.AmplificateurButton = uiradiobutton(app.GraphiqueButtonGroup);
            app.AmplificateurButton.Text = 'Amplificateur';
            app.AnimationButton = uiradiobutton(app.GraphiqueButtonGroup);
            app.AnimationButton.Text = 'Animation';
            app.CompterButton = uibutton(app.GraphiqueTab, 'push');
            app.CompterButton.ButtonPushedFcn = createCallbackFcn(app, @CompterButtonPushed, true);
            app.CompterButton.Text = 'Compter';
            app.CompterEditField = uieditfield(app.GraphiqueTab, 'numeric');
            app.CompterEditField.Editable = 'off';
            app.NombredepicesLabel = uilabel(app.GraphiqueTab);
            app.NombredepicesLabel.Text = 'Nombre de pièces';
            app.ChargerEditField = uieditfield(app.GraphiqueTab, 'text');
            app.ChargerButton = uibutton(app.GraphiqueTab, 'push');
            app.ChargerButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerButtonPushed, true);
            app.ChargerButton.Text = 'Charger';
            app.ParamtrisationTab = uitab(app.TabGroup);
            app.ParamtrisationTab.Title = 'Paramétrisation';
            app.ParamtresLabel = uilabel(app.ParamtrisationTab);
            app.ParamtresLabel.Text = 'Paramètres';
            app.ActionneurLabel = uilabel(app.ParamtrisationTab);
            app.ActionneurLabel.Text = 'Actionneur';
            app.ChampmagntiqueEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.ChampmagntiqueEditFieldLabel.Text = 'Champ magnétique';
            app.ChampmagntiqueEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.ChampmagntiqueEditField.Value = 0.1128;
            app.DiamtreEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.DiamtreEditFieldLabel.Text = 'Diamètre';
            app.DiamtreEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.DiamtreEditField.Value = 0.0406;
            app.NombredetoursEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.NombredetoursEditFieldLabel.Text = 'Nombre de tours';
            app.NombredetoursEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.NombredetoursEditField.Value = 66;
            app.CoefficientdefrottementEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.CoefficientdefrottementEditFieldLabel.Text = 'Coefficient de frottement';
            app.CoefficientdefrottementEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.InertieEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.InertieEditFieldLabel.Text = 'Inertie';
            app.InertieEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.AmplificateurLabel = uilabel(app.ParamtrisationTab);
            app.AmplificateurLabel.Text = 'Amplificateur';
            app.TensiondeoffsetEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.TensiondeoffsetEditFieldLabel.Text = 'Tension de offset';
            app.TensiondeoffsetEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.TensiondeoffsetEditField.Value = 2.55;
            app.GaindelampliaudioEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.GaindelampliaudioEditFieldLabel.Text = 'Gain de l''ampli audio';
            app.GaindelampliaudioEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.GaindelampliaudioEditField.Value = 4.02;
            app.ConstantedetempsdelampliaudioEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.ConstantedetempsdelampliaudioEditFieldLabel.Text = 'Constante de temps de l''ampli audio';
            app.ConstantedetempsdelampliaudioEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.ConstantedetempsdelampliaudioEditField.Value = 4.62e-06;
            app.InductancedelabobineEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.InductancedelabobineEditFieldLabel.Text = 'Inductance de la bobine';
            app.InductancedelabobineEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.InductancedelabobineEditField.Value = 0.00095;
            app.RsistancequivalentedepuissanceEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.RsistancequivalentedepuissanceEditFieldLabel.Text = 'Résistance équivalente de puissance';
            app.RsistancequivalentedepuissanceEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.RsistancequivalentedepuissanceEditField.Value = 1.01;
            app.RsistancedelabobineEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.RsistancedelabobineEditFieldLabel.Text = 'Résistance de la bobine';
            app.RsistancedelabobineEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.RsistancedelabobineEditField.Value = 1.43;
            app.ConstantecontrelectromotriceEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.ConstantecontrelectromotriceEditFieldLabel.Text = 'Constante contre-électromotrice';
            app.ConstantecontrelectromotriceEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.GaincouranttensionEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.GaincouranttensionEditFieldLabel.Text = 'Gain courant-tension';
            app.GaincouranttensionEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.GaincouranttensionEditField.Value = 3.04;
            app.CapteurdepositionLabel = uilabel(app.ParamtrisationTab);
            app.CapteurdepositionLabel.Text = 'Capteur de position';
            app.AmplitudedeloscillateurEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.AmplitudedeloscillateurEditFieldLabel.Text = 'Amplitude de l''oscillateur';
            app.AmplitudedeloscillateurEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.AmplitudedeloscillateurEditField.Value = 1.2;
            app.DistanceentreleslectrodesEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.DistanceentreleslectrodesEditFieldLabel.Text = 'Distance entre les électrodes';
            app.DistanceentreleslectrodesEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.DistanceentreleslectrodesEditField.Value = 0.005;
            app.Tensiondcalage1erampliEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.Tensiondcalage1erampliEditFieldLabel.Text = 'Tension décalage 1er ampli';
            app.Tensiondcalage1erampliEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.Tensiondcalage1erampliEditField.Value = -0.5;
            app.LameLabel = uilabel(app.ParamtrisationTab);
            app.LameLabel.Text = 'Lame';
            app.LargeurEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.LargeurEditFieldLabel.Text = 'Largeur';
            app.LargeurEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.LargeurEditField.ValueChangedFcn = createCallbackFcn(app, @ModuleDeYoungChanged, true);
            app.LargeurEditField.Value = 0.074;
            app.paisseurEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.paisseurEditFieldLabel.Text = 'Épaisseur';
            app.paisseurEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.paisseurEditField.ValueChangedFcn = createCallbackFcn(app, @ModuleDeYoungChanged, true);
            app.paisseurEditField.Value = 0.00159;
            app.LongueuractiveEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.LongueuractiveEditFieldLabel.Text = 'Longueur active';
            app.LongueuractiveEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.LongueuractiveEditField.ValueChangedFcn = createCallbackFcn(app, @ModuleDeYoungChanged, true);
            app.LongueuractiveEditField.Value = 0.24;
            app.MassechoisiegEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.MassechoisiegEditFieldLabel.Text = 'Masse choisie (g)';
            app.MassechoisiegEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.MassechoisiegEditField.ValueChangedFcn = createCallbackFcn(app, @MassechoisiegEditFieldValueChanged, true);
            app.SimulerButton_2 = uibutton(app.ParamtrisationTab, 'push');
            app.SimulerButton_2.ButtonPushedFcn = createCallbackFcn(app, @SimulerButtonPushed, true);
            app.SimulerButton_2.Text = 'Simuler';
            app.ValeurspardfautButton = uibutton(app.ParamtrisationTab, 'push');
            app.ValeurspardfautButton.ButtonPushedFcn = createCallbackFcn(app, @ValeurspardfautButtonPushed, true);
            app.ValeurspardfautButton.Text = 'Valeurs par défaut';
            app.MasseaffichegEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.MasseaffichegEditFieldLabel.Text = 'Masse affichée (g)';
            app.MasseaffichegEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.MasseaffichegEditField.Editable = 'off';
            app.Tensiondedcalagedu2eampliEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.Tensiondedcalagedu2eampliEditFieldLabel.Text = 'Tension de décalage du 2e ampli';
            app.Tensiondedcalagedu2eampliEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.Tensiondedcalagedu2eampliEditField.Value = 0.51;
            app.Rsistance1EditFieldLabel = uilabel(app.ParamtrisationTab);
            app.Rsistance1EditFieldLabel.Text = 'Résistance 1';
            app.Rsistance1EditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.Rsistance1EditField.Value = 1000;
            app.Rsistance2EditFieldLabel = uilabel(app.ParamtrisationTab);
            app.Rsistance2EditFieldLabel.Text = 'Résistance 2';
            app.Rsistance2EditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.Rsistance2EditField.Value = 1000;
            app.Rsistance4EditFieldLabel = uilabel(app.ParamtrisationTab);
            app.Rsistance4EditFieldLabel.Text = 'Résistance 4';
            app.Rsistance4EditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.Rsistance4EditField.Value = 4700;
            app.Rsistance3EditFieldLabel = uilabel(app.ParamtrisationTab);
            app.Rsistance3EditFieldLabel.Text = 'Résistance 3';
            app.Rsistance3EditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.Rsistance3EditField.Value = 1000;
            app.ConstantedrivateurEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.ConstantedrivateurEditFieldLabel.Text = 'Constante dérivateur';
            app.ConstantedrivateurEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.ConstanteintgrateurEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.ConstanteintgrateurEditFieldLabel.Text = 'Constante intégrateur';
            app.ConstanteintgrateurEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.ConstanteintgrateurEditField.Value = 0.132;
            app.ConstanteproportionelleEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.ConstanteproportionelleEditFieldLabel.Text = 'Constante proportionelle';
            app.ConstanteproportionelleEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.ConstanteproportionelleEditField.Value = 0.0067;
            app.PIDLabel = uilabel(app.ParamtrisationTab);
            app.PIDLabel.Text = 'PID';
            app.DensitEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.DensitEditFieldLabel.Text = 'Densité';
            app.DensitEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.DensitEditField.ValueChangedFcn = createCallbackFcn(app, @ModuleDeYoungChanged, true);
            app.DensitEditField.Value = 1937.18;
            app.FrquencenaturelleEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.FrquencenaturelleEditFieldLabel.Text = 'Fréquence naturelle';
            app.FrquencenaturelleEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.FrquencenaturelleEditField.ValueChangedFcn = createCallbackFcn(app, @ModuleDeYoungChanged, true);
            app.FrquencenaturelleEditField.Value = 12.17;
            app.SauvegarderParamEditField = uieditfield(app.ParamtrisationTab, 'text');
            app.SauvegarderParamButton = uibutton(app.ParamtrisationTab, 'push');
            app.SauvegarderParamButton.ButtonPushedFcn = createCallbackFcn(app, @SauvegarderParamButtonPushed, true);
            app.SauvegarderParamButton.Text = 'Sauvegarder';
            app.ChargerParamEditField = uieditfield(app.ParamtrisationTab, 'text');
            app.ChargerParamButton = uibutton(app.ParamtrisationTab, 'push');
            app.ChargerParamButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerParamButtonPushed, true);
            app.ChargerParamButton.Text = 'Charger';
            app.ModuledeYoungEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.ModuledeYoungEditFieldLabel.Text = 'Module de Young';
            app.ModuledeYoungEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.ModuledeYoungEditField.Value = 20060998527;
            app.AmortissementEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.AmortissementEditFieldLabel.Text = 'Amortissement';
            app.AmortissementEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.AmortissementEditField.Value = 0.8082;
            app.NombredepointsEditFieldLabel = uilabel(app.ParamtrisationTab);
            app.NombredepointsEditFieldLabel.Text = 'Nombre de points';
            app.NombredepointsEditField = uieditfield(app.ParamtrisationTab, 'numeric');
            app.NombredepointsEditField.Value = 19;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = balanceInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end